classdef SOLINDAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        SOLINDARPanel  matlab.ui.container.Panel
        SonarButton    matlab.ui.control.StateButton
        LidarButton    matlab.ui.control.StateButton
        FusionButton   matlab.ui.control.StateButton
        ONSwitch       matlab.ui.control.Switch
        LogSwitch      matlab.ui.control.Switch
        LoggingLamp    matlab.ui.control.Lamp
        LoggingLabel   matlab.ui.control.Label
        SerialLabel    matlab.ui.control.Label
        SerialList     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        % Serial
        s = 1;
        baudrate = 115200;
        buffersize = 8;
        % General parameters
        window = 1008;
        fs = 2800;
        posicion
        sonar
        lidar
        fileID = -1;
        pax
        LidarPlot
        SonarPlot
        FusionSLPlot
        PositionPlot
        % Variables for mockup
        t
    end
    
    methods (Access = private)
        % Callback para cuando se genera el evento de buffer lleno
        function readSerial(app)
            data = fread(app.s, app.buffersize, 'int8');
            [PositionRAW, LidarRAW, SonarRAW] = unpack(data);
            [Position, Lidar, Sonar] = ConvertData(PositionRAW, LidarRAW, SonarRAW);
            [FusionSL] = Fusion(Sonar, Lidar);
            
            % Shift Position Data
            app.LidarPlot.ThetaData = circshift(app.LidarPlot.ThetaData, 1);
            app.SonarPlot.ThetaData = circshift(app.LidarPlot.ThetaData, 1);
            app.FusionSLPlot.ThetaData = circshift(app.LidarPlot.ThetaData,1);
             
            if isvector(Position)
             app.LidarPlot.ThetaData(1) = Position(1,1);
             app.SonarPlot.ThetaData(1) = Position(1,1);
             app.FusionSLPlot.ThethaData(1) = Position(1,1);
             app.LidarPlot.RData(1) = Lidar(1,1);
             app.SonarPlot.RData(1) = Sonar(1,1);
             app.FusionSLPlot.RData(1) = FusionSL(1,1);
            
            else
                app.LidarPlot.ThetaData(1) = Position;
                app.SonarPlot.ThetaData(1) = Position;
                app.FusionSLPlot.ThetaData(1) = Position;
                app.LidarPlot.RData(1) = Lidar;
                app.SonarPlot.RData(1) = Sonar;
                app.FusionSLPlot.RData(1) = FusionSL;
            end
            
            % Shift Sensor Data
            app.LidarPlot.RData = circshift(app.LidarPlot.RData, 1);
            app.SonarPlot.RData = circshift(app.SonarPlot.RData, 1);
            app.FusionSLPlot.RData = circshift(app.FusionSLPlot.RData,1);
         
            
            % Save the data if the logging is enable
            if strcmp(app.LogSwitch.Value, 'On') && ~isempty(fopen(app.fileID))
                fprintf(app.fileID,'%s, %.2f, %.2f, %.2f\n', (datestr(datetime('now')))', round(Position(1), 3)', round(Lidar(1), 2)', round(Sonar(1), 2)');
            end
        end
        
        function initSerial(app)
            app.s.BaudRate = app.baudrate;
            app.s.InputBufferSize = app.buffersize;
            app.s.BytesAvailableFcnCount = app.buffersize;      % Número de bytes en el buffer de entrada para generar evento
            app.s.BytesAvailableFcnMode = 'byte';
            app.s.BytesAvailableFcn = @(~,~)app.readSerial;
        end
        
        function initPolarscatter(app)
            % Create polaraxes object
            app.pax = polaraxes(app.UIFigure);
            
            
            % Customize the polar axes
            app.pax.NextPlot = 'add';
            app.pax.ThetaZeroLocation = 'top';
            app.pax.ThetaDir = 'clockwise';
            app.pax.ThetaTickLabel = {'0', '30', '60', '90', '120', '150', '180', '-150', '-120', '-90', '-60', '-30'};
            app.pax.RLim = [0 100];
            app.pax.RTick = 0:20:200;
            app.pax.ThetaMinorGrid = 'on';
            app.pax.Position = [-0.03 0.1100 0.7750 0.7750];
        end
    
        function timerStart(app)
            disp('Starting timer...');
        end
    
        function timerPeriodic(app)
            PositionRAW = randi([0 64]);
            LidarRAW = randi([0 4096]);
            SonarRAW = randi([0 512]);
            
            [Position, Lidar, Sonar] = ConvertData(PositionRAW, LidarRAW, SonarRAW);
            [FusionSL] = Fusion(Sonar, Lidar);
            
            app.LidarPlot.ThetaData = circshift(app.LidarPlot.ThetaData, 1);
            app.SonarPlot.ThetaData = circshift(app.LidarPlot.ThetaData, 1);
            app.FusionSLPlot.ThetaData = circshift(app.LidarPlot.ThetaData, 1);
            %app.PositionPlot.ThetaData = circshift([0 app.LidarPlot.ThetaData]);
            
            
            app.LidarPlot.ThetaData(1) = Position;
            app.SonarPlot.ThetaData(1) = Position;
            app.FusionSLPlot.ThetaData(1) = Position;
            %app.PositionPlot.ThetaData = [0 Position];
           
            
            app.LidarPlot.RData = circshift(app.LidarPlot.RData, 1);
            app.SonarPlot.RData = circshift(app.SonarPlot.RData, 1);
            app.FusionSLPlot.RData = circshift(app.FusionSLPlot.RData, 1);
            %app.PositionPlot.RData = circshift(app.PositionPlot.RData);


            app.LidarPlot.RData(1) = Lidar;
            app.SonarPlot.RData(1) = Sonar;
            app.FusionSLPlot.RData(1) = FusionSL;
            %app.PositionPlot.RData = [0 5];
            
            
            % Save the data if the logging is enable
            if strcmp(app.LogSwitch.Value, 'On') && ~isempty(fopen(app.fileID))
                fprintf(app.fileID,'%s, %.2f, %.2f, %.2f\n', (datestr(datetime('now')))', round(Position(1), 3)', round(Lidar(1), 2)', round(Sonar(1), 2)');
            end
        end
    
        function timerStop(app)
            disp('Stoping timer');
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initPolarscatter;
            app.LidarPlot = polarscatter(app.pax, repelem(0, 5), repelem(1000, 5), 'filled');
            app.SonarPlot = polarscatter(app.pax, repelem(0, 5), repelem(1000, 5), 'filled');
            app.FusionSLPlot = polarscatter(app.pax, repelem(0, 5), repelem(1000, 5), 'filled');
            
            app.t = timer('StartFcn',@(~,~)app.timerStart, 'StopFcn',@(~,~)app.timerStop, 'TimerFcn', @(~,~)app.timerPeriodic, 'ExecutionMode', 'fixedDelay', 'Period', 0.25);
            start(app.t); % Comment this line to test only the serial
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % To close the serial port correctly
            if isnumeric(app.s)
            elseif strcmp(app.s.Status, 'open')
                fclose(app.s);
            end
            % To close the file if it is open
            if ~isempty(fopen(app.fileID))
                fclose(app.fileID);
            end
            stop(app.t); delete(app.t); % Only for testing
            delete(app);
        end

        % Value changed function: SonarButton
        function SonarButtonValueChanged(app, event)
            value = app.SonarButton.Value;
            app.SonarPlot.Visible = value;
        end

        % Value changed function: LidarButton
        function LidarButtonValueChanged(app, event)
            value = app.LidarButton.Value;
            app.LidarPlot.Visible = value;
        end

        % Value changed function: FusionButton
        function FusionButtonValueChanged(app, event)
            value = app.FusionButton.Value;
            app.FusionSLPlot.Visible = value;
        end

        % Value changed function: LogSwitch
        function LogSwitchValueChanged(app, event)
            value = app.LogSwitch.Value;
            app.LoggingLamp.Enable = value;
            if strcmp(value, 'On')
                % if the file exists read it, if it doesn't create it
                if isfile('log.csv')
                    app.fileID = fopen('log.csv', 'rt+');
                else
                    app.fileID = fopen('log.csv', 'wt+');
                end
                fseek(app.fileID, 0, 'bof');
                fprintf(app.fileID, 'Timestamp, Position, Lidar, Sonar\n');
                fseek(app.fileID, 0, 'eof');
            else
                fclose(app.fileID);
            end
            
        end

        % Value changed function: SerialList
        function SerialListValueChanged(app, event)
            value = app.SerialList.Value;
            app.SerialList.Items = seriallist;
        end

        % Value changed function: ONSwitch
        function ONSwitchValueChanged(app, event)
            value = app.ONSwitch.Value;
            if strcmp(value, 'Connect')
                app.s = serial(app.SerialList.Value);
                app.initSerial;
                fopen(app.s);
            else
               fclose(app.s);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'SOLINDAR APP';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SOLINDARPanel = uipanel(app.UIFigure);
            app.SOLINDARPanel.Title = 'SOLINDAR';
            app.SonarButton = uibutton(app.SOLINDARPanel, 'state');
            app.SonarButton.ValueChangedFcn = createCallbackFcn(app, @SonarButtonValueChanged, true);
            app.SonarButton.Text = 'SONAR';
            app.LidarButton = uibutton(app.SOLINDARPanel, 'state');
            app.LidarButton.ValueChangedFcn = createCallbackFcn(app, @LidarButtonValueChanged, true);
            app.LidarButton.Text = 'LIDAR';
            app.FusionButton = uibutton(app.SOLINDARPanel, 'state');
            app.FusionButton.ValueChangedFcn = createCallbackFcn(app, @FusionButtonValueChanged, true);
            app.FusionButton.Text = 'Fusión';
            app.ONSwitch = uiswitch(app.SOLINDARPanel, 'slider');
            app.ONSwitch.Items = {'Disconnect', 'Connect'};
            app.ONSwitch.ValueChangedFcn = createCallbackFcn(app, @ONSwitchValueChanged, true);
            app.ONSwitch.Value = 'Disconnect';
            app.LogSwitch = uiswitch(app.SOLINDARPanel, 'slider');
            app.LogSwitch.ItemsData = {'Off', 'On'};
            app.LogSwitch.ValueChangedFcn = createCallbackFcn(app, @LogSwitchValueChanged, true);
            app.LoggingLamp = uilamp(app.SOLINDARPanel);
            app.LoggingLamp.Enable = 'off';
            app.LoggingLabel = uilabel(app.SOLINDARPanel);
            app.LoggingLabel.Text = 'Logging';
            app.SerialLabel = uilabel(app.SOLINDARPanel);
            app.SerialLabel.Text = 'Serial';
            app.SerialList = uidropdown(app.SOLINDARPanel);
            app.SerialList.Items = {'----------', 'Refresh'};
            app.SerialList.ValueChangedFcn = createCallbackFcn(app, @SerialListValueChanged, true);
            app.SerialList.Value = 'Refresh';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SOLINDAR

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end