classdef Towers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TowersUIFigure                matlab.ui.Figure
        MensajeRecibidoLabel          matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        MensajeaenviarEditFieldLabel  matlab.ui.control.Label
        MensajeaenviarEditField       matlab.ui.control.NumericEditField
        UbicaciondezonasPanel         matlab.ui.container.Panel
        Torre1Label                   matlab.ui.control.Label
        Torre1DropDown                matlab.ui.control.DropDown
        Torre2Label                   matlab.ui.control.Label
        Torre2DropDown                matlab.ui.control.DropDown
        Torre3DropDownLabel           matlab.ui.control.Label
        Torre3DropDown                matlab.ui.control.DropDown
        Torre4DropDownLabel           matlab.ui.control.Label
        Torre4DropDown                matlab.ui.control.DropDown
        EnviarButton                  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        % Serial
        s = serial('COM3');
        baudrate = 115200;
        buffersize = 4;
        % General parameters
        message   = 0;
        position1 = 0;
        position2 = 0;
        position3 = 0;
        position4 = 0;
        % Timer
        t;
        timerPeriod = 1;
    end
    
    methods (Access = private)
        
        % Callback para cuando se genera el evento de buffer lleno
        function readSerial(app)
            data = fread(app.s, app.buffersize, 'uint8');
            %disp(data)
            [message_received] = unpack_2(data);
            disp(message_received)
            app.Label.Text = string(message_received);
        end
        
        function initSerial(app)
            app.s.BaudRate = app.baudrate;
            app.s.InputBufferSize = app.buffersize;
            app.s.BytesAvailableFcnCount = app.buffersize;      % Número de bytes en el buffer de entrada para generar evento
            app.s.BytesAvailableFcnMode = 'byte';
            app.s.BytesAvailableFcn = @(~,~)app.readSerial;
        end
        
        
        function Enviar(app)
            message_sent = Pack(app.message, app.position1, app.position2, app.position3, app.position4);
            fwrite(app.s, message_sent);
            %disp(message_sent);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initSerial;
            fopen(app.s);
            app.t = timer('TimerFcn', @(~,~)app.Enviar, 'ExecutionMode', 'fixedDelay', 'Period', app.timerPeriod);
        end

        % Close request function: TowersUIFigure
        function TowersUIFigureCloseRequest(app, event)
            fclose(app.s);
            stop(app.t);
            delete(app.t)
            delete(app)
        end

        % Value changed function: Torre1DropDown
        function Torre1DropDownValueChanged(app, event)
            value = app.Torre1DropDown.Value;
            app.position1 = str2double(value);
        end

        % Value changed function: Torre2DropDown
        function Torre2DropDownValueChanged(app, event)
            value = app.Torre2DropDown.Value;
            app.position2 = str2double(value);
        end

        % Value changed function: Torre3DropDown
        function Torre3DropDownValueChanged(app, event)
            value = app.Torre3DropDown.Value;
            app.position3 = str2double(value);
        end

        % Value changed function: Torre4DropDown
        function Torre4DropDownValueChanged(app, event)
            value = app.Torre4DropDown.Value;
            app.position4 = str2double(value);
        end

        % Value changed function: MensajeaenviarEditField
        function MensajeaenviarEditFieldValueChanged(app, event)
            value = app.MensajeaenviarEditField.Value;
            app.message = value;
        end

        % Value changed function: EnviarButton
        function EnviarButtonValueChanged(app, event)
            value = app.EnviarButton.Value;
            switch value
                case 0
                    stop(app.t)
                case 1
                    start(app.t)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TowersUIFigure = uifigure;
            app.TowersUIFigure.Name = 'Towers';
            app.TowersUIFigure.CloseRequestFcn = createCallbackFcn(app, @TowersUIFigureCloseRequest, true);
            app.MensajeRecibidoLabel = uilabel(app.TowersUIFigure);
            app.MensajeRecibidoLabel.Text = 'Mensaje Recibido:';
            app.Label = uilabel(app.TowersUIFigure);
            app.Label.Text = '';
            app.MensajeaenviarEditFieldLabel = uilabel(app.TowersUIFigure);
            app.MensajeaenviarEditFieldLabel.Text = 'Mensaje a enviar';
            app.MensajeaenviarEditField = uieditfield(app.TowersUIFigure, 'numeric');
            app.MensajeaenviarEditField.ValueChangedFcn = createCallbackFcn(app, @MensajeaenviarEditFieldValueChanged, true);
            app.UbicaciondezonasPanel = uipanel(app.TowersUIFigure);
            app.UbicaciondezonasPanel.Title = 'Ubicacion de zonas';
            app.Torre1Label = uilabel(app.UbicaciondezonasPanel);
            app.Torre1Label.Text = 'Torre 1';
            app.Torre1DropDown = uidropdown(app.UbicaciondezonasPanel);
            app.Torre1DropDown.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.Torre1DropDown.ValueChangedFcn = createCallbackFcn(app, @Torre1DropDownValueChanged, true);
            app.Torre1DropDown.Value = '0';
            app.Torre2Label = uilabel(app.UbicaciondezonasPanel);
            app.Torre2Label.Text = 'Torre 2';
            app.Torre2DropDown = uidropdown(app.UbicaciondezonasPanel);
            app.Torre2DropDown.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.Torre2DropDown.ValueChangedFcn = createCallbackFcn(app, @Torre2DropDownValueChanged, true);
            app.Torre2DropDown.Value = '0';
            app.Torre3DropDownLabel = uilabel(app.UbicaciondezonasPanel);
            app.Torre3DropDownLabel.Text = 'Torre 3';
            app.Torre3DropDown = uidropdown(app.UbicaciondezonasPanel);
            app.Torre3DropDown.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.Torre3DropDown.ValueChangedFcn = createCallbackFcn(app, @Torre3DropDownValueChanged, true);
            app.Torre3DropDown.Value = '0';
            app.Torre4DropDownLabel = uilabel(app.UbicaciondezonasPanel);
            app.Torre4DropDownLabel.Text = 'Torre 4';
            app.Torre4DropDown = uidropdown(app.UbicaciondezonasPanel);
            app.Torre4DropDown.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.Torre4DropDown.ValueChangedFcn = createCallbackFcn(app, @Torre4DropDownValueChanged, true);
            app.Torre4DropDown.Value = '0';
            app.EnviarButton = uibutton(app.UbicaciondezonasPanel, 'state');
            app.EnviarButton.ValueChangedFcn = createCallbackFcn(app, @EnviarButtonValueChanged, true);
            app.EnviarButton.Text = 'Enviar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Towers

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TowersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TowersUIFigure)
        end
    end
end